classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        EditFieldLabel   matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        EditField2Label  matlab.ui.control.Label
        EditField2       matlab.ui.control.EditField
        Button           matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Label            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_4
        function ButtonPushed(app, event)
             app.Label.Text = num2str(str2num(app.EditField.Value)/str2num(app.EditField2.Value))
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            app.Label.Text = num2str(str2num(app.EditField.Value)+str2num(app.EditField2.Value))
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Label.Text = num2str(str2num(app.EditField.Value)-str2num(app.EditField2.Value))
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             app.Label.Text = num2str(str2num(app.EditField.Value)*str2num(app.EditField2.Value))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '*';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_4.Text = '/';
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end