classdef color < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        RedLabel          matlab.ui.control.Label
        GreenLabel        matlab.ui.control.Label
        BlueLabel         matlab.ui.control.Label
        EditFieldLabel    matlab.ui.control.Label
        EditFieldr        matlab.ui.control.EditField
        EditField2Label   matlab.ui.control.Label
        EditFieldg        matlab.ui.control.EditField
        EditField3Label   matlab.ui.control.Label
        EditFieldb        matlab.ui.control.EditField
        RedSliderLabel    matlab.ui.control.Label
        RedSlider         matlab.ui.control.Slider
        GreenSliderLabel  matlab.ui.control.Label
        GreenSlider       matlab.ui.control.Slider
        BlueSliderLabel   matlab.ui.control.Label
        BlueSlider        matlab.ui.control.Slider
        Label             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditFieldr
        function EditFieldrValueChanged(app, event)
            valueR = str2double(app.EditFieldr.Value);
            valueG = str2double(app.EditFieldg.Value);
            valueB = str2double(app.EditFieldb.Value);
            app.RedSlider.Value = valueR
            app.Label.BackgroundColor = [valueR,valueG,valueB]
        end

        % Value changed function: EditFieldg
        function EditFieldgValueChanged(app, event)
            valueR = str2double(app.EditFieldr.Value);
            valueG = str2double(app.EditFieldg.Value);
            valueB = str2double(app.EditFieldb.Value);
            app.GreenSlider.Value = valueG
            app.Label.BackgroundColor = [valueR,valueG,valueB]
        end

        % Value changed function: EditFieldb
        function EditFieldbValueChanged(app, event)
            valueR = str2double(app.EditFieldr.Value);
            valueG = str2double(app.EditFieldg.Value);
            valueB = str2double(app.EditFieldb.Value);
            app.BlueSlider.Value = valueB
            app.Label.BackgroundColor = [valueR,valueG,valueB]
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            value = app.RedSlider.Value;
            app.EditFieldr.Value=num2str(value)
            valueR = str2double(app.EditFieldr.Value);
            valueG = str2double(app.EditFieldg.Value);
            valueB = str2double(app.EditFieldb.Value);
            app.EditFieldr.Value=num2str(valueR)
            app.Label.BackgroundColor = [valueR,valueG,valueB]
            
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            value = app.GreenSlider.Value;
            app.EditFieldg.Value=num2str(value)
            valueR = str2double(app.EditFieldr.Value);
            valueG = str2double(app.EditFieldg.Value);
            valueB = str2double(app.EditFieldb.Value);
            app.EditFieldg.Value=num2str(valueG)
            app.Label.BackgroundColor = [valueR,valueG,valueB]
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            value = app.BlueSlider.Value;
            app.EditFieldb.Value=num2str(value)
            valueR = str2double(app.EditFieldr.Value);
            valueG = str2double(app.EditFieldg.Value);
            valueB = str2double(app.EditFieldb.Value);
            app.EditFieldb.Value=num2str(valueB)
            app.Label.BackgroundColor = [valueR,valueG,valueB]
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RedLabel = uilabel(app.UIFigure);
            app.RedLabel.Text = 'Red';
            app.GreenLabel = uilabel(app.UIFigure);
            app.GreenLabel.Text = 'Green';
            app.BlueLabel = uilabel(app.UIFigure);
            app.BlueLabel.Text = 'Blue';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditFieldr = uieditfield(app.UIFigure, 'text');
            app.EditFieldr.ValueChangedFcn = createCallbackFcn(app, @EditFieldrValueChanged, true);
            app.EditFieldr.Value = '0';
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditFieldg = uieditfield(app.UIFigure, 'text');
            app.EditFieldg.ValueChangedFcn = createCallbackFcn(app, @EditFieldgValueChanged, true);
            app.EditFieldg.Value = '0';
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditFieldb = uieditfield(app.UIFigure, 'text');
            app.EditFieldb.ValueChangedFcn = createCallbackFcn(app, @EditFieldbValueChanged, true);
            app.EditFieldb.Value = '0';
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Limits = [0 1];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Limits = [0 1];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Limits = [0 1];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = color

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end