classdef IPapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        FileMenu  matlab.ui.container.Menu
        OpenMenu  matlab.ui.container.Menu
        SaveMenu  matlab.ui.container.Menu
        ExitMenu  matlab.ui.container.Menu
        Menu2     matlab.ui.container.Menu
        UIAxes    matlab.ui.control.UIAxes
        UIAxes2   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
             [app.Data.FileName, app.Data.PathName] = uigetfile( ...
{ '*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff',...
'Image Files (*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff)';...
'*.bmp','Windows Bitmap (*.bmp)';...
'*.jpg;*.jpeg','JPEG (*.jpg;*.jpeg)';...
'*.pbm','Portable Bitmap (*.pbm)';...
'*.pgm','Portable Graymap (*.pgm)';...
'*.png','Portable Network Graphics (*.png)';...
'*.ppm','Portable Pixmap (*.ppm)';...
'*.tif;*.tiff','Tagged Image File Format (*.tif;*.tiff)';...
'*.*', 'All Files (*.*)'},...
'Select an Image File to Open');

if (app.Data.FileName)==0
return
else
%read in image
app.Data.img = imread([app.Data.PathName, app.Data.FileName]);
app.Data.result = app.Data.img;
%show image
imshow(app.Data.img, 'Parent', app.UIAxes);
%get image information
[app.Data.rows, app.Data.cols, app.Data.dim] = size(app.Data.img);
info = imfinfo([app.Data.PathName, app.Data.FileName]);
app.Data.bitdepth = info.BitDepth;
app.Data.ColorType = info.ColorType;
end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [filename, pathname, filterindex] = uiputfile( ...
{ '*.bmp','Windows Bitmap (*.bmp)'; ...
'*.jpg','JPEG (*.jpg)'; ...
'*.pbm','Portable Bitmap (*.pbm)'; ...
'*.pgm','Portable Graymap (*.pgm)'; ...
'*.png','Portable Network Graphics (*.png)'; ...
'*.ppm','Portable Pixmap (*.ppm)'; ...
'*.tif','Tagged Image File Format (*.tif)'}, ...
'Save Image as',...
'untitled');

if (filename)==0
return
else
fmt(1,:)='bmp';
fmt(2,:)='jpg';
fmt(3,:)='pbm';
fmt(4,:)='pgm';
fmt(5,:)='png';
fmt(6,:)='ppm';
fmt(7,:)='tif';
%write out image
imwrite(app.Data.result, [pathname, filename], fmt(filterindex,:));
end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IPapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end