classdef IPapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        OpenMenu                 matlab.ui.container.Menu
        SaveMenu                 matlab.ui.container.Menu
        ExitMenu                 matlab.ui.container.Menu
        WatermarkingMenu         matlab.ui.container.Menu
        BitPlaneMenu             matlab.ui.container.Menu
        LSBMenu                  matlab.ui.container.Menu
        HidingMenu               matlab.ui.container.Menu
        ExtractMenu              matlab.ui.container.Menu
        PointProcessingMenu      matlab.ui.container.Menu
        ComplementMenu           matlab.ui.container.Menu
        HistogramMenu            matlab.ui.container.Menu
        StretchingMenu           matlab.ui.container.Menu
        EqualizationMenu         matlab.ui.container.Menu
        FilterMenu               matlab.ui.container.Menu
        AverageMenu              matlab.ui.container.Menu
        GaussianMenu             matlab.ui.container.Menu
        LaplacianMenu            matlab.ui.container.Menu
        LoGMenu                  matlab.ui.container.Menu
        UnsharpMenu              matlab.ui.container.Menu
        CustomizedMenu           matlab.ui.container.Menu
        CustomizedGaussianMenu   matlab.ui.container.Menu
        ROIMenu                  matlab.ui.container.Menu
        pointMenu                matlab.ui.container.Menu
        LineMenu                 matlab.ui.container.Menu
        RectangleMenu            matlab.ui.container.Menu
        PolygonMenu              matlab.ui.container.Menu
        EllipseMenu              matlab.ui.container.Menu
        FreehandMenu             matlab.ui.container.Menu
        ROIaverageMenu           matlab.ui.container.Menu
        MosaicMenu               matlab.ui.container.Menu
        ThresholdingMenu         matlab.ui.container.Menu
        OtsuMenu                 matlab.ui.container.Menu
        UserMenu                 matlab.ui.container.Menu
        EdgeMenu                 matlab.ui.container.Menu
        prewittMenu              matlab.ui.container.Menu
        SobelMenu                matlab.ui.container.Menu
        RobertsMenu              matlab.ui.container.Menu
        LogMenu                  matlab.ui.container.Menu
        zerocrossMenu            matlab.ui.container.Menu
        CannyMenu                matlab.ui.container.Menu
        approxcannyMenu          matlab.ui.container.Menu
        HoughMenu                matlab.ui.container.Menu
        SnakeMenu                matlab.ui.container.Menu
        FourierMenu              matlab.ui.container.Menu
        LowpassMenu              matlab.ui.container.Menu
        HighpassMenu             matlab.ui.container.Menu
        ButterworthLowMenu       matlab.ui.container.Menu
        ButterworthHighMenu      matlab.ui.container.Menu
        GaussianLowMenu          matlab.ui.container.Menu
        GaussianHighMenu         matlab.ui.container.Menu
        DeepLearnMenu            matlab.ui.container.Menu
        GoogLeNetMenu            matlab.ui.container.Menu
        ThresholdingSliderLabel  matlab.ui.control.Label
        ThresholdingSlider       matlab.ui.control.Slider
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
             [app.Data.FileName, app.Data.PathName] = uigetfile( ...
{ '*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff',...
'Image Files (*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff)';...
'*.bmp','Windows Bitmap (*.bmp)';...
'*.jpg;*.jpeg','JPEG (*.jpg;*.jpeg)';...
'*.pbm','Portable Bitmap (*.pbm)';...
'*.pgm','Portable Graymap (*.pgm)';...
'*.png','Portable Network Graphics (*.png)';...
'*.ppm','Portable Pixmap (*.ppm)';...
'*.tif;*.tiff','Tagged Image File Format (*.tif;*.tiff)';...
'*.*', 'All Files (*.*)'},...
'Select an Image File to Open');
figure(app.UIFigure);
if (app.Data.FileName)==0
return
else
%read in image
app.Data.img = imread([app.Data.PathName, app.Data.FileName]);
app.Data.result = app.Data.img;
%show image
imshow(app.Data.img, 'Parent', app.UIAxes);
%get image information
[app.Data.rows, app.Data.cols, app.Data.dim] = size(app.Data.img);
info = imfinfo([app.Data.PathName, app.Data.FileName]);
app.Data.bitdepth = info.BitDepth;
app.Data.ColorType = info.ColorType;
end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [filename, pathname, filterindex] = uiputfile( ...
{ '*.bmp','Windows Bitmap (*.bmp)'; ...
'*.jpg','JPEG (*.jpg)'; ...
'*.pbm','Portable Bitmap (*.pbm)'; ...
'*.pgm','Portable Graymap (*.pgm)'; ...
'*.png','Portable Network Graphics (*.png)'; ...
'*.ppm','Portable Pixmap (*.ppm)'; ...
'*.tif','Tagged Image File Format (*.tif)'}, ...
'Save Image as',...
'untitled');
figure(app.UIFigure);
if (filename)==0
return
else
fmt(1,:)='bmp';
fmt(2,:)='jpg';
fmt(3,:)='pbm';
fmt(4,:)='pgm';
fmt(5,:)='png';
fmt(6,:)='ppm';
fmt(7,:)='tif';
%write out image
imwrite(app.Data.result, [pathname, filename], fmt(filterindex,:));
end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: BitPlaneMenu
        function BitPlaneMenuSelected(app, event)
    if (app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
    %extract bitplane image
    imgbp = zeros(size(app.Data.img));
    for i=1:8
        imgbp(:,:,i) = bitget(app.Data.img,i);
    end
    %show image
    SI = figure;
    figure(SI);
    for i=1:8
        subplot(2,4,i);
        imshow(imgbp(:,:,i), []);
        title(['Bitplane ', num2str(i)]);
    end
    else
        warndlg('The image is not an 8-bit grayscale image.','Warning')
    return
    end
        end

        % Menu selected function: LSBMenu
        function LSBMenuSelected(app, event)
            [FileName, PathName] = uigetfile( ...
    { '*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff',...
'Image Files (*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff)';
'*.bmp','Windows Bitmap (*.bmp)';...
'*.jpg;*.jpeg','JPEG (*.jpg;*.jpeg)';...
'*.pbm','Portable Bitmap (*.pbm)';...
'*.pgm','Portable Graymap (*.pgm)';...
'*.png','Portable Network Graphics (*.png)';...
'*.ppm','Portable Pixmap (*.ppm)';...
'*.tif;*.tiff','Tagged Image File Format (*.tif;*.tiff)';...
'*.*', 'All Files (*.*)'},...
'Select a Watermark Image to Open');
            
            if (FileName)==0
            return
            else
                if (app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %read in watermark image
                water_img = imread([PathName,FileName]);
                water_info = imfinfo([PathName,FileName]);
                [rows, cols, dim] = size(water_img);
            else
                warndlg('The source image is not an 8-bit grayscale image.','Warning');
            return;
                end
            end
            if (water_info.BitDepth)==1 %watermark is binary
                app.Data.result(1:rows,1:cols)=bitset(app.Data.img(1:rows,1:cols),1,water_img);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
                %compute and show PSNR
                mse = sum(sum((double(app.Data.img)-double(app.Data.result))^2))/(app.Data.rows*app.Data.cols);
                PSNR = 10*log10(255*255/mse)
            else
                warndlg('The watermark image is not binary.','Warning');
            return;
            end
            
        end

        % Menu selected function: HidingMenu
        function HidingMenuSelected(app, event)
             [FileName, PathName] = uigetfile( ...
    { '*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff',...
'Image Files (*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff)';
'*.bmp','Windows Bitmap (*.bmp)';...
'*.jpg;*.jpeg','JPEG (*.jpg;*.jpeg)';...
'*.pbm','Portable Bitmap (*.pbm)';...
'*.pgm','Portable Graymap (*.pgm)';...
'*.png','Portable Network Graphics (*.png)';...
'*.ppm','Portable Pixmap (*.ppm)';...
'*.tif;*.tiff','Tagged Image File Format (*.tif;*.tiff)';...
'*.*', 'All Files (*.*)'},...
'Select a Watermark Image to Open');
            
            if (FileName)==0
            return
            else
                if (app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %read in watermark image
                water_img = imread([PathName,FileName]);
                 water_img = rand(3,5)>0.5
                 stream= RandStream('mcg16807', 'Seed', 0)
                 randnum = rand(stream, 3, 5)   
                [elements idx1] = sort(randnum(:))
                 water_img1 = reshape(water_img(idx1), 3, 5)
                water_info = imfinfo([PathName,FileName]);
                [rows, cols, dim] = size(water_img);
            else
                warndlg('The source image is not an 8-bit grayscale image.','Warning');
            return;
                end
            end
            if (water_info.BitDepth)==1 %watermark is binary
                app.Data.result(1:rows,1:cols)=bitset(app.Data.img(1:rows,1:cols),1,water_img);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
                %compute and show PSNR
                mse = sum(sum((double(app.Data.img)-double(app.Data.result))^2))/(app.Data.rows*app.Data.cols);
                PSNR = 10*log10(255*255/mse)
            else
                warndlg('The watermark image is not binary.','Warning');
            return;
            end


        end

        % Menu selected function: ExtractMenu
        function ExtractMenuSelected(app, event)
            stream= RandStream('mcg16807', 'Seed', 0)
            randnum = rand(stream, 3, 5)
            [elements idx2] = sort(randnum(:))
            water_img2(idx2) = water_img
            water_img2 = reshape(water_img2, 3, 5)
        end

        % Menu selected function: ComplementMenu
        function ComplementMenuSelected(app, event)
            %image complement
            app.Data.result = imcomplement(app.Data.img);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: HistogramMenu
        function HistogramMenuSelected(app, event)
            %image histogram
            if (app.Data.dim)==1 && (app.Data.bitdepth)==8
                SI = figure;
                figure(SI);
                imhist(app.Data.img);
            elseif(app.Data.dim)==3
                SI = figure;
                figure(SI);
                imhist(app.Data.img);
                S2 = figure;
                figure(S2);
                imhist(app.Data.img(:,:,1)); 
                S3 = figure;
                figure(S3);
                imhist(app.Data.img(:,:,2)); 
                S4 = figure;
                figure(S4);
                imhist(app.Data.img(:,:,3));    
             else
                warndlg('The image should be an 8-bit grayscale image.','Warning')
            return
            end
        end

        % Menu selected function: StretchingMenu
        function StretchingMenuSelected(app, event)
            %image stretching
            if (app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                app.Data.result = imadjust(app.Data.img);
            elseif(app.Data.dim)==3
                    test = rgb2ntsc(app.Data.img);
                    test(:,:,1)= imadjust(test(:,:,1));
                    
                    app.Data.result = ntsc2rgb(test);
                  
            else
                warndlg('The image should be an 8-bit grayscale image.','Warning')
            return
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: EqualizationMenu
        function EqualizationMenuSelected(app, event)
            %image stretching
            if (app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                app.Data.result = histeq(app.Data.img);
            elseif(app.Data.dim)==3
                app.Data.result = histeq(app.Data.img); 
            else
                warndlg('The image should be an 8-bit grayscale image.','Warning')
                return
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            s1 = figure;
            figure(s1);
            imhist(app.Data.result);
        end

        % Menu selected function: AverageMenu
        function AverageMenuSelected(app, event)
            %Determine if input is structure array field
        if isfield(app.Data, 'img')
        %Create predefined 2-D filter
            h = fspecial('average', 7);
        %image 2-D digital filter
            app.Data.result = imfilter(app.Data.img, h);
        %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        else
            warndlg('The image file is not open.','Warning')
        return
        end
        end

        % Menu selected function: GaussianMenu
        function GaussianMenuSelected(app, event)
            %Determine if input is structure array field
        if isfield(app.Data, 'img')
        %Create predefined 2-D filter
            h = fspecial('laplacian');
        %image 2-D digital filter
            app.Data.result = imfilter(app.Data.img, h);
        %show image
        imshow(app.Data.result, 'Parent', app.UIAxes2);
        else
            warndlg('The image file is not open.','Warning')
        return
        end
        end

        % Menu selected function: LaplacianMenu
        function LaplacianMenuSelected(app, event)
             if isfield(app.Data, 'img')
        %Create predefined 2-D filter
            h = fspecial('gaussian');
        %image 2-D digital filter
            app.Data.result = imfilter(app.Data.img, h);
        %show image
        imshow(app.Data.result, 'Parent', app.UIAxes2);
        else
            warndlg('The image file is not open.','Warning')
        return
            end
        end

        % Menu selected function: LoGMenu
        function LoGMenuSelected(app, event)
            if isfield(app.Data, 'img')
        %Create predefined 2-D filter
            h = fspecial('log');
        %image 2-D digital filter
            app.Data.result = imfilter(app.Data.img, h);
        %show image
        imshow(app.Data.result, 'Parent', app.UIAxes2);
        else
            warndlg('The image file is not open.','Warning')
        return
            end
        end

        % Menu selected function: UnsharpMenu
        function UnsharpMenuSelected(app, event)
             if isfield(app.Data, 'img')
        %Create predefined 2-D filter
            h = fspecial('unsharp');
        %image 2-D digital filter
            app.Data.result = imfilter(app.Data.img, h);
        %show image
        imshow(app.Data.result, 'Parent', app.UIAxes2);
        else
            warndlg('The image file is not open.','Warning')
        return
            end
        end

        % Menu selected function: CustomizedMenu
        function CustomizedMenuSelected(app, event)
            %Create and open input dialog box
        prompt={'Rows (Mask Height):', 'Columns (Mask Width):'};
        name='Customized Filter';
        numlines=1;
        defaultanswer={'7', '7'};
        answer = inputdlg(prompt, name, numlines, defaultanswer);
        if isempty(answer) || isempty(answer{1}) || isempty(answer{2})
            warndlg('Error inputs.','Warning')
            return
        else
%Create predefined 2-D filter
            h = fspecial('average', [str2double(answer{1}) str2double(answer{2})]);
%image 2-D digital filter
            app.Data.result = imfilter(app.Data.img, h);
%show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end
        end

        % Menu selected function: CustomizedGaussianMenu
        function CustomizedGaussianMenuSelected(app, event)
                %Create and open input dialog box
        prompt={'Rows (Mask Height):', 'Columns (Mask Width):','sigma:'};
        name='Customized Filter';
        numlines=2;
        defaultanswer={'7', '7','0.5'};
        answer = inputdlg(prompt, name, numlines, defaultanswer);
        if isempty(answer) || isempty(answer{1}) || isempty(answer{2})
            warndlg('Error inputs.','Warning')
            return
        else
%Create predefined 2-D filter
             h = fspecial('gaussian', [str2double(answer{1}) str2double(answer{2})],str2double(answer{3}));
%image 2-D digital filter
            app.Data.result = imfilter(app.Data.img, h);
%show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end
        end

        % Menu selected function: pointMenu
        function pointMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            %draw point
            imshow(app.Data.img, 'Parent', app.UIAxes)
            roi = drawpoint(app.UIAxes, 'Color', 'r');
            roi.Position
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: LineMenu
        function LineMenuSelected(app, event)
             %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            %draw point
            imshow(app.Data.img, 'Parent', app.UIAxes)
            roi = drawline(app.UIAxes, 'Color', 'r');
            roi.Position
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: RectangleMenu
        function RectangleMenuSelected(app, event)
             %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            %draw point
            imshow(app.Data.img, 'Parent', app.UIAxes)
            roi = drawrectangle(app.UIAxes, 'Color', 'r');
            roi.Position = round(roi.Position);
            roi.Position
            app.Data.result = app.Data.img(roi.Position(2)+1:roi.Position(2)+roi.Position(4), ...
roi.Position(1)+1:roi.Position(1)+roi.Position(3),:);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: PolygonMenu
        function PolygonMenuSelected(app, event)
               %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            %draw point
            imshow(app.Data.img, 'Parent', app.UIAxes)
            roi = drawpolygon(app.UIAxes, 'Color', 'r')
            roi.Position
            %create binary mask image from roi
            mask = createMask(roi);
            app.Data.result = uint8(mask.*double(app.Data.img));
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: EllipseMenu
        function EllipseMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            %draw ellipse
            imshow(app.Data.img, 'Parent', app.UIAxes)
            roi = drawellipse(app.UIAxes, 'Color', 'r');
            roi.Position
            %create binary mask image from roi
            mask = createMask(roi);
            app.Data.result = uint8(mask.*double(app.Data.img));
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: FreehandMenu
        function FreehandMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            %draw freehand
            imshow(app.Data.img, 'Parent', app.UIAxes)
            roi = drawfreehand(app.UIAxes, 'Color', 'r');
            %create binary mask image from roi
            mask = createMask(roi);
            app.Data.result = uint8(mask.*double(app.Data.img));
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: ROIaverageMenu
        function ROIaverageMenuSelected(app, event)
            if (app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %set ROI function disable
                set(app.ROIMenu,'Enable','off');
                %draw rectangle
                imshow(app.Data.img, 'Parent', app.UIAxes)
                roi = drawrectangle(app.UIAxes, 'Color', 'r');
                roi.Position
                %create binary mask image from roi
                mask = createMask(roi);
                %Create predefined 2-D filter
                f = fspecial('average',[25,25]);
                %image ROI filter
                app.Data.result = roifilt2(f, app.Data.img, mask);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
                %set ROI function enable
                set(app.ROIMenu,'Enable','on');
            else
                warndlg('The image should be an 8-bit grayscale image.','Warning')
                return
            end
        end

        % Menu selected function: MosaicMenu
        function MosaicMenuSelected(app, event)
        prompt={'Mask:'};
        name='Mosaic';
        numlines=1;
        defaultanswer='3';
        pix_grp = inputdlg(prompt, name, numlines,{num2str(defaultanswer)});
        %set ROI function disable
        %set(app.ROIMenu,'Enable','off');
        %draw rectangle
        imshow(app.Data.img, 'Parent', app.UIAxes)
        roi = drawrectangle(app.UIAxes, 'Color', 'r');
        roi.Position = round(roi.Position);
        roimg = app.Data.img(roi.Position(2)+1:roi.Position(2)+roi.Position(4), ...
        roi.Position(1)+1:roi.Position(1)+roi.Position(3),:);
        mosaic = imresize(roimg,[floor(roi.Position(4)/str2double(cell2mat(pix_grp))) floor(roi.Position(3)/str2double(cell2mat(pix_grp)))]);
        mosaic = imresize(mosaic,[roi.Position(4), roi.Position(3)],'nearest');
        app.Data.result = app.Data.img;
        app.Data.result(roi.Position(2)+1:roi.Position(2)+roi.Position(4), roi.Position(1)+1:roi.Position(1)+roi.Position(3),:) = mosaic;
        imshow(app.Data.result, 'Parent', app.UIAxes2);
        set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: OtsuMenu
        function OtsuMenuSelected(app, event)
            if (app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %Global image threshold using Otsu's method
                level = graythresh(app.Data.img);
                app.Data.result = imbinarize(app.Data.img, level);
                level = round(level*255)
                [counts,x] = imhist(app.Data.img, 256);
                SI = figure;
                figure(SI);
                stem(x,counts,'.');
                hold on;
                xline(level,'--r');
                hold off;
                
               elseif(app.Data.dim)==3
                   image_r = app.Data.img(:,:,1);
                   image_g = app.Data.img(:,:,2);
                   image_b = app.Data.img(:,:,3);
                   
                   level_r = graythresh(app.Data.img(:,:,1));
                   result_R = imbinarize(app.Data.img(:,:,1), level_r);
                   level_r = round(level_r*255)
                   [counts,x] = imhist(app.Data.img(:,:,1), 256);
                   SI = figure;
                   figure(SI);
                   stem(x,counts,'.');
                   hold on;
                   xline(level_r,'--r');
                   hold off;
                   
                   level_g = graythresh(app.Data.img(:,:,2));
                   result_G = imbinarize(app.Data.img(:,:,2), level_g);
                   level_g = round(level_g*255)
                   [counts,x] = imhist(app.Data.img(:,:,2), 256);
                   SI = figure;
                   figure(SI);
                   stem(x,counts,'.');
                   hold on;
                   xline(level_g,'--g');
                   hold off;
                   
                   level_b = graythresh(app.Data.img(:,:,3));
                   result_B = imbinarize(app.Data.img(:,:,3), level_b);
                   level_b = round(level_b*255)
                   [counts,x] = imhist(app.Data.img(:,:,3), 256);
                   SI = figure;
                   figure(SI);
                   stem(x,counts,'.');
                   hold on;
                   xline(level_b,'--b');
                   hold off;
                   
                   app.Data.result=result_R|result_G|result_B;


            else
                warndlg('The image must be a grayscale image.','Warning')
            return
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Value changed function: ThresholdingSlider
        function ThresholdingSliderValueChanged(app, event)
            value = app.ThresholdingSlider.Value;
            if (app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %Global image threshold using slider's value
                app.Data.result = imbinarize(app.Data.img, value/255);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            elseif(app.Data.dim)==3
                app.Data.result = imbinarize(rgb2gray(app.Data.img), value/255);
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image must be a grayscale image.','Warning');
                return
            end
        end

        % Menu selected function: UserMenu
        function UserMenuSelected(app, event)
            if app.UserMenu.Checked==0
                set(app.UserMenu, 'Checked', 'on');
                set(app.ThresholdingSlider, 'visible', 'on');
                set(app.ThresholdingSliderLabel, 'visible', 'on');
            else
                set(app.UserMenu, 'Checked', 'off');
                set(app.ThresholdingSlider, 'visible', 'off');
                set(app.ThresholdingSliderLabel, 'visible', 'off');
            end
        end

        % Menu selected function: prewittMenu
        function prewittMenuSelected(app, event)
            if (app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'Prewitt');
            elseif(app.Data.dim==3)
                app.Data.result = edge(rgb2gray(app.Data.img), 'prewitt');
            else
                warndlg('The image cannot be processed.', 'Warning')
                return
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: SobelMenu
        function SobelMenuSelected(app, event)
             if (app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'Sobel');
            elseif(app.Data.dim==3)
                app.Data.result = edge(rgb2gray(app.Data.img), 'Sobel');    
            
            else
                warndlg('The image cannot be processed.', 'Warning')
                return
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: RobertsMenu
        function RobertsMenuSelected(app, event)
             if (app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'Roberts');
             elseif(app.Data.dim==3)
                 image_r = app.Data.img(:,:,1);
                 image_g = app.Data.img(:,:,2);
                 image_b = app.Data.img(:,:,3);
                 result_R=edge(image_r, 'Roberts');
                 result_G=edge(image_g, 'Roberts');
                 result_B=edge(image_b, 'Roberts');
                 
                 app.Data.result=result_R|result_G|result_B;
            else
                warndlg('The image cannot be processed.', 'Warning')
                return
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: LogMenu
        function LogMenuSelected(app, event)
             if (app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'log');
             elseif(app.Data.dim==3)
                app.Data.result = edge(rgb2gray(app.Data.img), 'log');    
            
            else
                warndlg('The image cannot be processed.', 'log')
                return
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: zerocrossMenu
        function zerocrossMenuSelected(app, event)
             if (app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'zerocross');
            elseif(app.Data.dim==3)
                app.Data.result = edge(rgb2gray(app.Data.img), 'zerocross'); 
            else
                warndlg('The image cannot be processed.', 'zerocross');
                return
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: CannyMenu
        function CannyMenuSelected(app, event)
             if (app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'Canny');
            elseif(app.Data.dim==3)
                app.Data.result = edge(rgb2gray(app.Data.img), 'Canny');
            else
                warndlg('The image cannot be processed.', 'Canny')
                return
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: approxcannyMenu
        function approxcannyMenuSelected(app, event)
             if (app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'approxcanny');
             elseif(app.Data.dim==3)
                app.Data.result = edge(rgb2gray(app.Data.img), 'approxcanny');
            else
                warndlg('The image cannot be processed.', 'approxcanny')
                return
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: HoughMenu
        function HoughMenuSelected(app, event)
            if (app.Data.dim==1) %grayscale image
            %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'Canny');
                
            elseif(app.Data.dim==3)
                app.Data.result = edge(rgb2gray(app.Data.img), 'Canny');
            else
                warndlg('The image cannot be processed.','Warning')
                return
            end
            %hough transform
            [H,T,R] = hough(app.Data.result);
            P = houghpeaks(H,3); %設定前3個最大數目(最明顯的直線)
            lines = houghlines(app.Data.result,T,R,P);
            %show image with lines
            app.Data.result = uint8(255*app.Data.result);
            for k = 1:length(lines)
                app.Data.result = insertShape(app.Data.result,'Line',...
                [lines(k).point1,lines(k).point2],'LineWidth',5,'Color','r');
            end
            imshow(app.Data.result, 'Parent', app.UIAxes2)
        end

        % Menu selected function: LowpassMenu
        function LowpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2), 2)==0
                w = size(app.Data.img, 2)/2;
                wr = -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr = -w:w;
            end
            
            %create a small circle mask
            [x,y] = meshgrid(wr,hr);
            z = sqrt(x.^2+y.^2);
            c = (z < 15);
            %Fourier lowpass filter
            app.Data.result = ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result < 0) ) = 0;
            app.Data.result( find(app.Data.result > 255) ) = 255;
            app.Data.result = uint8(app.Data.result);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);

        end

        % Menu selected function: HighpassMenu
        function HighpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2), 2)==0
                w = size(app.Data.img, 2)/2;
                wr = -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr = -w:w;
            end
            
            %create a small circle mask
            [x,y] = meshgrid(wr,hr);
            z = sqrt(x.^2+y.^2);
            c = (z > 15);
            %Fourier lowpass filter
            app.Data.result = ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result < 0) ) = 0;
            app.Data.result( find(app.Data.result > 255) ) = 255;
            app.Data.result = uint8(app.Data.result);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: ButterworthLowMenu
        function ButterworthLowMenuSelected(app, event)
             if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2), 2)==0
                w = size(app.Data.img, 2)/2;
                wr = -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr = -w:w;
            end
            
            %create a small circle mask
            D = 15;
            n = 1;
            %create a Butterworth lowpass mask
            [x,y] = meshgrid(wr,hr);
            c = 1./(1+ ( (x.^2+y.^2) /D ).^(2*n) );
            %Fourier lowpass filter
            app.Data.result = ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result < 0) ) = 0;
            app.Data.result( find(app.Data.result > 255) ) = 255;
            app.Data.result = uint8(app.Data.result);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: ButterworthHighMenu
        function ButterworthHighMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2), 2)==0
                w = size(app.Data.img, 2)/2;
                wr = -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr = -w:w;
            end
            
            %create a small circle mask
            D = 15;
            n = 1;
            %create a Butterworth lowpass mask
            [x,y] = meshgrid(wr,hr);
            c = 1-1./(1+ ( (x.^2+y.^2) /D ).^(2*n) );
            %Fourier lowpass filter
            app.Data.result = ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result < 0) ) = 0;
            app.Data.result( find(app.Data.result > 255) ) = 255;
            app.Data.result = uint8(app.Data.result);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: GaussianLowMenu
        function GaussianLowMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2), 2)==0
                w = size(app.Data.img, 2)/2;
                wr = -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr = -w:w;
            end
            
            %create a small circle mask
            hsize = size(app.Data.img, 1:2);
            sigma = 10;
            %create a Gaussian lowpass mask
            c = mat2gray(fspecial('gaussian', hsize, sigma));
            %Fourier lowpass filter
            app.Data.result = ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result < 0) ) = 0;
            app.Data.result( find(app.Data.result > 255) ) = 255;
            app.Data.result = uint8(app.Data.result);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: GaussianHighMenu
        function GaussianHighMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2), 2)==0
                w = size(app.Data.img, 2)/2;
                wr = -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr = -w:w;
            end
            
            %create a small circle mask
            hsize = size(app.Data.img, 1:2);
            sigma = 10;
            %create a Gaussian lowpass mask
            c = 1-mat2gray(fspecial('gaussian', hsize, sigma));
            %Fourier lowpass filter
            app.Data.result = ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result < 0) ) = 0;
            app.Data.result( find(app.Data.result > 255) ) = 255;
            app.Data.result = uint8(app.Data.result);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: SnakeMenu
        function SnakeMenuSelected(app, event)
            if size(app.Data.img,3)==1 %grayscale image
                imshow(app.Data.img, 'Parent', app.UIAxes);
                SI = figure;
                figure(SI);
                imshow(app.Data.img,[]);
                %snake initialization
                cd D:Digital_Image_Processing_Class\snake\snake
                [x,y] = snakeinit(1);
                [x,y] = snakeinterp(x,y,3,1);
                snakedisp(x,y,'r');
                % Compute its edge map
                [gx,gy] = gradient(double(app.Data.img));
                magG = sqrt(gx.*gx+gy.*gy);
                %compute GVF of edge map
                [u,v] = GVF(magG, 0.2, 80);
                mag = sqrt(u.*u+v.*v);
                px = u./(mag+1e-10); py = v./(mag+1e-10);% snake deformation
                for i=1:20
                    [x,y] = snakedeform(x,y,0.05,0,1,0.6,px,py,5);
                    [x,y] = snakeinterp(x,y,3,1);
                if (rem(i,2) == 0)
                    snakedisp(x,y,'r');
                    pause(0.5);
                end
                end
                cd ..
                %show image
                imshow(app.Data.img, 'Parent', app.UIAxes2);
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2,x,y,'r');
                hold(app.UIAxes2, 'off');
          else
                warndlg('The image cannot beprocessed.','Warning')
                return
            end
        end

        % Menu selected function: GoogLeNetMenu
        function GoogLeNetMenuSelected(app, event)
            if size(app.Data.img,3)==3 %color image
                % load the pretrained GoogLeNet network
                net = googlenet;
                % get the input size from layer 1
                inputSize = net.Layers(1).InputSize
                % get the classification output names from the last layer
                classNames = net.Layers(end).ClassNames;
                % resize the image to the input size of the network
                I = imresize(app.Data.img,inputSize(1:2));
                [label, scores] = classify(net, I);
                %show image
                imshow(app.Data.img, 'Parent', app.UIAxes2);
                app.UIAxes2.Title.String = string(label)+", "+num2str(100*scores(classNames==label),3)+"%";
            else
                warndlg("The image must be a color image.",'Warning')
                return
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.WatermarkingMenu = uimenu(app.UIFigure);
            app.WatermarkingMenu.Text = 'Watermarking';
            app.BitPlaneMenu = uimenu(app.WatermarkingMenu);
            app.BitPlaneMenu.MenuSelectedFcn = createCallbackFcn(app, @BitPlaneMenuSelected, true);
            app.BitPlaneMenu.Text = 'BitPlane';
            app.LSBMenu = uimenu(app.WatermarkingMenu);
            app.LSBMenu.MenuSelectedFcn = createCallbackFcn(app, @LSBMenuSelected, true);
            app.LSBMenu.Text = 'LSB';
            app.HidingMenu = uimenu(app.WatermarkingMenu);
            app.HidingMenu.MenuSelectedFcn = createCallbackFcn(app, @HidingMenuSelected, true);
            app.HidingMenu.Text = 'Hiding';
            app.ExtractMenu = uimenu(app.WatermarkingMenu);
            app.ExtractMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtractMenuSelected, true);
            app.ExtractMenu.Text = 'Extract';
            app.PointProcessingMenu = uimenu(app.UIFigure);
            app.PointProcessingMenu.Text = 'Point Processing';
            app.ComplementMenu = uimenu(app.PointProcessingMenu);
            app.ComplementMenu.MenuSelectedFcn = createCallbackFcn(app, @ComplementMenuSelected, true);
            app.ComplementMenu.Text = 'Complement';
            app.HistogramMenu = uimenu(app.PointProcessingMenu);
            app.HistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramMenuSelected, true);
            app.HistogramMenu.Text = 'Histogram';
            app.StretchingMenu = uimenu(app.PointProcessingMenu);
            app.StretchingMenu.MenuSelectedFcn = createCallbackFcn(app, @StretchingMenuSelected, true);
            app.StretchingMenu.Text = 'Stretching';
            app.EqualizationMenu = uimenu(app.PointProcessingMenu);
            app.EqualizationMenu.MenuSelectedFcn = createCallbackFcn(app, @EqualizationMenuSelected, true);
            app.EqualizationMenu.Text = 'Equalization';
            app.FilterMenu = uimenu(app.UIFigure);
            app.FilterMenu.Text = 'Filter';
            app.AverageMenu = uimenu(app.FilterMenu);
            app.AverageMenu.MenuSelectedFcn = createCallbackFcn(app, @AverageMenuSelected, true);
            app.AverageMenu.Text = 'Average';
            app.GaussianMenu = uimenu(app.FilterMenu);
            app.GaussianMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenuSelected, true);
            app.GaussianMenu.Text = 'Gaussian';
            app.LaplacianMenu = uimenu(app.FilterMenu);
            app.LaplacianMenu.MenuSelectedFcn = createCallbackFcn(app, @LaplacianMenuSelected, true);
            app.LaplacianMenu.Text = 'Laplacian';
            app.LoGMenu = uimenu(app.FilterMenu);
            app.LoGMenu.MenuSelectedFcn = createCallbackFcn(app, @LoGMenuSelected, true);
            app.LoGMenu.Text = 'LoG';
            app.UnsharpMenu = uimenu(app.FilterMenu);
            app.UnsharpMenu.MenuSelectedFcn = createCallbackFcn(app, @UnsharpMenuSelected, true);
            app.UnsharpMenu.Text = 'Unsharp';
            app.CustomizedMenu = uimenu(app.FilterMenu);
            app.CustomizedMenu.MenuSelectedFcn = createCallbackFcn(app, @CustomizedMenuSelected, true);
            app.CustomizedMenu.Text = 'Customized';
            app.CustomizedGaussianMenu = uimenu(app.FilterMenu);
            app.CustomizedGaussianMenu.MenuSelectedFcn = createCallbackFcn(app, @CustomizedGaussianMenuSelected, true);
            app.CustomizedGaussianMenu.Text = 'CustomizedGaussian';
            app.ROIMenu = uimenu(app.UIFigure);
            app.ROIMenu.Text = 'ROI';
            app.pointMenu = uimenu(app.ROIMenu);
            app.pointMenu.MenuSelectedFcn = createCallbackFcn(app, @pointMenuSelected, true);
            app.pointMenu.Text = 'point';
            app.LineMenu = uimenu(app.ROIMenu);
            app.LineMenu.MenuSelectedFcn = createCallbackFcn(app, @LineMenuSelected, true);
            app.LineMenu.Text = 'Line';
            app.RectangleMenu = uimenu(app.ROIMenu);
            app.RectangleMenu.MenuSelectedFcn = createCallbackFcn(app, @RectangleMenuSelected, true);
            app.RectangleMenu.Text = 'Rectangle';
            app.PolygonMenu = uimenu(app.ROIMenu);
            app.PolygonMenu.MenuSelectedFcn = createCallbackFcn(app, @PolygonMenuSelected, true);
            app.PolygonMenu.Text = 'Polygon';
            app.EllipseMenu = uimenu(app.ROIMenu);
            app.EllipseMenu.MenuSelectedFcn = createCallbackFcn(app, @EllipseMenuSelected, true);
            app.EllipseMenu.Text = 'Ellipse';
            app.FreehandMenu = uimenu(app.ROIMenu);
            app.FreehandMenu.MenuSelectedFcn = createCallbackFcn(app, @FreehandMenuSelected, true);
            app.FreehandMenu.Text = 'Freehand';
            app.ROIaverageMenu = uimenu(app.ROIMenu);
            app.ROIaverageMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIaverageMenuSelected, true);
            app.ROIaverageMenu.Text = 'ROIaverage';
            app.MosaicMenu = uimenu(app.ROIMenu);
            app.MosaicMenu.MenuSelectedFcn = createCallbackFcn(app, @MosaicMenuSelected, true);
            app.MosaicMenu.Text = 'Mosaic';
            app.ThresholdingMenu = uimenu(app.UIFigure);
            app.ThresholdingMenu.Text = 'Thresholding';
            app.OtsuMenu = uimenu(app.ThresholdingMenu);
            app.OtsuMenu.MenuSelectedFcn = createCallbackFcn(app, @OtsuMenuSelected, true);
            app.OtsuMenu.Text = 'Otsu';
            app.UserMenu = uimenu(app.ThresholdingMenu);
            app.UserMenu.MenuSelectedFcn = createCallbackFcn(app, @UserMenuSelected, true);
            app.UserMenu.Text = 'User';
            app.EdgeMenu = uimenu(app.UIFigure);
            app.EdgeMenu.Text = 'Edge';
            app.prewittMenu = uimenu(app.EdgeMenu);
            app.prewittMenu.MenuSelectedFcn = createCallbackFcn(app, @prewittMenuSelected, true);
            app.prewittMenu.Text = 'prewitt';
            app.SobelMenu = uimenu(app.EdgeMenu);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @SobelMenuSelected, true);
            app.SobelMenu.Text = 'Sobel';
            app.RobertsMenu = uimenu(app.EdgeMenu);
            app.RobertsMenu.MenuSelectedFcn = createCallbackFcn(app, @RobertsMenuSelected, true);
            app.RobertsMenu.Text = 'Roberts';
            app.LogMenu = uimenu(app.EdgeMenu);
            app.LogMenu.MenuSelectedFcn = createCallbackFcn(app, @LogMenuSelected, true);
            app.LogMenu.Text = 'Log';
            app.zerocrossMenu = uimenu(app.EdgeMenu);
            app.zerocrossMenu.MenuSelectedFcn = createCallbackFcn(app, @zerocrossMenuSelected, true);
            app.zerocrossMenu.Text = 'zerocross';
            app.CannyMenu = uimenu(app.EdgeMenu);
            app.CannyMenu.MenuSelectedFcn = createCallbackFcn(app, @CannyMenuSelected, true);
            app.CannyMenu.Text = 'Canny';
            app.approxcannyMenu = uimenu(app.EdgeMenu);
            app.approxcannyMenu.MenuSelectedFcn = createCallbackFcn(app, @approxcannyMenuSelected, true);
            app.approxcannyMenu.Text = 'approxcanny';
            app.HoughMenu = uimenu(app.EdgeMenu);
            app.HoughMenu.MenuSelectedFcn = createCallbackFcn(app, @HoughMenuSelected, true);
            app.HoughMenu.Text = 'Hough';
            app.SnakeMenu = uimenu(app.EdgeMenu);
            app.SnakeMenu.MenuSelectedFcn = createCallbackFcn(app, @SnakeMenuSelected, true);
            app.SnakeMenu.Text = 'Snake';
            app.FourierMenu = uimenu(app.UIFigure);
            app.FourierMenu.Text = 'Fourier';
            app.LowpassMenu = uimenu(app.FourierMenu);
            app.LowpassMenu.MenuSelectedFcn = createCallbackFcn(app, @LowpassMenuSelected, true);
            app.LowpassMenu.Text = 'Lowpass';
            app.HighpassMenu = uimenu(app.FourierMenu);
            app.HighpassMenu.MenuSelectedFcn = createCallbackFcn(app, @HighpassMenuSelected, true);
            app.HighpassMenu.Text = 'Highpass';
            app.ButterworthLowMenu = uimenu(app.FourierMenu);
            app.ButterworthLowMenu.MenuSelectedFcn = createCallbackFcn(app, @ButterworthLowMenuSelected, true);
            app.ButterworthLowMenu.Text = 'ButterworthLow';
            app.ButterworthHighMenu = uimenu(app.FourierMenu);
            app.ButterworthHighMenu.MenuSelectedFcn = createCallbackFcn(app, @ButterworthHighMenuSelected, true);
            app.ButterworthHighMenu.Text = 'ButterworthHigh';
            app.GaussianLowMenu = uimenu(app.FourierMenu);
            app.GaussianLowMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianLowMenuSelected, true);
            app.GaussianLowMenu.Text = 'GaussianLow';
            app.GaussianHighMenu = uimenu(app.FourierMenu);
            app.GaussianHighMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianHighMenuSelected, true);
            app.GaussianHighMenu.Text = 'GaussianHigh';
            app.DeepLearnMenu = uimenu(app.UIFigure);
            app.DeepLearnMenu.Text = 'Deep Learn';
            app.GoogLeNetMenu = uimenu(app.DeepLearnMenu);
            app.GoogLeNetMenu.MenuSelectedFcn = createCallbackFcn(app, @GoogLeNetMenuSelected, true);
            app.GoogLeNetMenu.Text = 'GoogLeNet';
            app.ThresholdingSliderLabel = uilabel(app.UIFigure);
            app.ThresholdingSliderLabel.Visible = 'off';
            app.ThresholdingSliderLabel.Text = 'Thresholding';
            app.ThresholdingSlider = uislider(app.UIFigure);
            app.ThresholdingSlider.Limits = [0 255];
            app.ThresholdingSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdingSliderValueChanged, true);
            app.ThresholdingSlider.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IPapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end